classdef cadastrar_usuario < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EmailLabel                   matlab.ui.control.Label
        EmailEditField               matlab.ui.control.EditField
        CadastrarButton              matlab.ui.control.Button
        SenhaLabel                   matlab.ui.control.Label
        SenhaPasswordField           matlab.ui.control.EditField
        NomeLabel                    matlab.ui.control.Label
        NomeEditField                matlab.ui.control.EditField
        ConfirmarsenhaLabel          matlab.ui.control.Label
        ConfirmarSenhaPasswordField  matlab.ui.control.EditField
        CadastrodoUsurioLabel        matlab.ui.control.Label
        VoltaraoLoginButton          matlab.ui.control.Button
        UsernameLabel                matlab.ui.control.Label
        UsernameField                matlab.ui.control.EditField
        Image                        matlab.ui.control.Image
        ProblemasButton              matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];
        end

        % Button pushed function: VoltaraoLoginButton
        function VoltaraoLoginButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual (cadastro)
            tela_login();          % Abre novamente a tela de login
        end

        % Button pushed function: CadastrarButton
        function CadastrarButtonPushed(app, event)
            % Lê os valores dos campos
                disp('Botão Cadastrar pressionado');

            nome = app.NomeEditField.Value;
            email = app.EmailEditField.Value;
            senha = app.SenhaPasswordField.Value;
            confirmarSenha = app.ConfirmarSenhaPasswordField.Value;
            username = app.UsernameField.Value;
            
            % Validação do username
            if isempty(username)
                uialert(app.UIFigure, 'Por favor, preencha o campo Username.', 'Erro');
                return;
            end

            % Validação básica
            if isempty(nome) || isempty(email) || isempty(senha) || isempty(confirmarSenha)
                uialert(app.UIFigure, 'Por favor, preencha todos os campos.', 'Erro');
                return;
            end
            
            % Validação simples do email: verificar se tem '@' e '.'
            if ~contains(email, '@') || ~contains(email, '.')
                uialert(app.UIFigure, 'Por favor, insira um email válido.', 'Erro');
                return;
            end
        
            % Validação de senha
            if ~strcmp(senha, confirmarSenha)
                uialert(app.UIFigure, 'As senhas não coincidem.', 'Erro');
                return;
            end

        % Dados para enviar à API
        url = 'https://projeto-iolanda.onrender.com/cadastro';  % sua URL da API
        data = struct('nome', nome, 'email', email, 'senha', senha, 'username', username);
        options = weboptions('MediaType', 'application/json');
        
        try
            resposta = webwrite(url, data, options);
            if isfield(resposta, 'status') && strcmp(resposta.status, 'ok')
                uialert(app.UIFigure, 'Usuário cadastrado com sucesso!', 'Sucesso');
                delete(app.UIFigure);
                tela_login();
            else
                % Caso a API retorne status diferente de 'ok' no corpo da resposta (pode acontecer)
                msg = 'Erro no cadastro.';
                if isfield(resposta, 'mensagem')
                    msg = resposta.mensagem;
                end
                uialert(app.UIFigure, msg, 'Erro');
            end
    
        catch ME
            % Captura erro HTTP (como 400) e extrai mensagem JSON da API
            if strcmp(ME.identifier, 'MATLAB:webservices:HTTP400StatusCodeError')
                if isprop(ME, 'Response') && isfield(ME.Response, 'Body') && isfield(ME.Response.Body, 'Data')
                    responseData = ME.Response.Body.Data;
                    if isnumeric(responseData)
                        responseData = char(responseData');
                    end
                    try
                        msgJson = jsondecode(responseData);
                        if isfield(msgJson, 'mensagem')
                            uialert(app.UIFigure, msgJson.mensagem, 'Erro no cadastro');
                        else
                            uialert(app.UIFigure, 'Erro desconhecido no cadastro.', 'Erro');
                        end
                    catch
                        uialert(app.UIFigure, 'Erro inesperado ao interpretar resposta do servidor.', 'Erro');
                    end
                else
                    uialert(app.UIFigure, 'Erro no cadastro: email ou usuário já cadastrado.', 'Erro');
                end
            else
                % Outros erros (ex: conexão)
                uialert(app.UIFigure, 'Não foi possível conectar ao servidor. Verifique sua internet.', 'Erro');
            end
        end
        end

        % Button pushed function: ProblemasButton
        function ProblemasButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual (cadastro)
            contato_login();          % Abre novamente a tela de suporte
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EmailLabel = uilabel(app.UIFigure);
            app.EmailLabel.Text = {'Email:'; ''};
            app.EmailEditField = uieditfield(app.UIFigure, 'text');
            app.CadastrarButton = uibutton(app.UIFigure, 'push');
            app.CadastrarButton.ButtonPushedFcn = createCallbackFcn(app, @CadastrarButtonPushed, true);
            app.CadastrarButton.Text = 'Cadastrar';
            app.SenhaLabel = uilabel(app.UIFigure);
            app.SenhaLabel.Text = {'Senha:'; ''};
            app.SenhaPasswordField = uieditfield(app.UIFigure, 'text');
            app.NomeLabel = uilabel(app.UIFigure);
            app.NomeLabel.Text = {'Nome:'; ''};
            app.NomeEditField = uieditfield(app.UIFigure, 'text');
            app.ConfirmarsenhaLabel = uilabel(app.UIFigure);
            app.ConfirmarsenhaLabel.Text = {'Confirmar senha:'; ''};
            app.ConfirmarSenhaPasswordField = uieditfield(app.UIFigure, 'text');
            app.CadastrodoUsurioLabel = uilabel(app.UIFigure);
            app.CadastrodoUsurioLabel.Text = 'Cadastro do Usuário';
            app.VoltaraoLoginButton = uibutton(app.UIFigure, 'push');
            app.VoltaraoLoginButton.ButtonPushedFcn = createCallbackFcn(app, @VoltaraoLoginButtonPushed, true);
            app.VoltaraoLoginButton.Text = 'Voltar ao Login';
            app.UsernameLabel = uilabel(app.UIFigure);
            app.UsernameLabel.Text = 'Username:';
            app.UsernameField = uieditfield(app.UIFigure, 'text');
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.ProblemasButton = uibutton(app.UIFigure, 'push');
            app.ProblemasButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemasButtonPushed, true);
            app.ProblemasButton.Text = 'Problemas com o Cadastro?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cadastrar_usuario

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end