classdef contato_login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        Image                 matlab.ui.control.Image
        VoltarLoginButton     matlab.ui.control.Button
        SuporteLabel          matlab.ui.control.Label
        Texto1Label           matlab.ui.control.Label
        Texto2Label           matlab.ui.control.Label
        EmailEditField        matlab.ui.control.EditField
        CopiarButton          matlab.ui.control.Button
        EmailLabel            matlab.ui.control.Label
        MensagemEditField     matlab.ui.control.EditField
        CopiarMensagemButton  matlab.ui.control.Button
        MensagemLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];           
            app.EmailEditField.Value = 'alunounivapsjc@gmail.com';
        end

        % Button pushed function: VoltarLoginButton
        function VoltarLoginButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual (cadastro)
            tela_login();          % Abre novamente a tela de login
        end

        % Button pushed function: CopiarButton
        function CopiarButtonPushed(app, event)
            clipboard('copy', 'alunounivapsjc@gmail.com');
            uialert(app.UIFigure, 'E-mail copiado para a área de transferência!', 'Copiado');
        end

        % Button pushed function: CopiarMensagemButton
        function CopiarMensagemButtonPushed(app, event)
            texto = app.MensagemEditField.Value;  % nome do componente
            clipboard('copy', texto);
            uialert(app.UIFigure, 'Mensagem copiada! Cole no seu e-mail para enviar.', 'Mensagem');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.VoltarLoginButton = uibutton(app.UIFigure, 'push');
            app.VoltarLoginButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarLoginButtonPushed, true);
            app.VoltarLoginButton.Text = 'Voltar à Tela de Login';
            app.SuporteLabel = uilabel(app.UIFigure);
            app.SuporteLabel.Text = 'Suporte ao Usuário';
            app.Texto1Label = uilabel(app.UIFigure);
            app.Texto1Label.Text = 'Se você esqueceu sua senha, teve problemas para acessar sua conta ou precisa criar um novo cadastro usando o mesmo e-mail,';
            app.Texto2Label = uilabel(app.UIFigure);
            app.Texto2Label.Text = 'entre em contato com a equipe do IOLANDA.';
            app.EmailEditField = uieditfield(app.UIFigure, 'text');
            app.EmailEditField.Editable = 'off';
            app.CopiarButton = uibutton(app.UIFigure, 'push');
            app.CopiarButton.ButtonPushedFcn = createCallbackFcn(app, @CopiarButtonPushed, true);
            app.CopiarButton.Text = 'Copiar ';
            app.EmailLabel = uilabel(app.UIFigure);
            app.EmailLabel.Text = 'Email de suporte:';
            app.MensagemEditField = uieditfield(app.UIFigure, 'text');
            app.CopiarMensagemButton = uibutton(app.UIFigure, 'push');
            app.CopiarMensagemButton.ButtonPushedFcn = createCallbackFcn(app, @CopiarMensagemButtonPushed, true);
            app.CopiarMensagemButton.Text = 'Copiar ';
            app.MensagemLabel = uilabel(app.UIFigure);
            app.MensagemLabel.Text = 'Mensagem (opcional):';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = contato_login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end