classdef conversao_dados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SelecionarPastaButton           matlab.ui.control.Button
        PastaSelecionadaLabel           matlab.ui.control.Label
        ConverterDadosButton            matlab.ui.control.Button
        VoltarMenuButton                matlab.ui.control.Button
        Image                           matlab.ui.control.Image
        SelecioneaPastaparaExportarButton  matlab.ui.control.Button
        SelecioneaestaoDropDownLabel    matlab.ui.control.Label
        SelecioneDropDown               matlab.ui.control.DropDown
        StatusTextAreaLabel             matlab.ui.control.Label
        StatusTextArea                  matlab.ui.control.TextArea
        NenhumapastaselecionadaLabel    matlab.ui.control.Label
        PastaSelecionadaLabel2          matlab.ui.control.Label
        NenhumapastaselecionadaLabel2   matlab.ui.control.Label
        TratamentodosdadosdoUDIDALabel  matlab.ui.control.Label
        ParametrosOMNIButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        selectedFolder = ""; % Caminho da pasta selecionada
        exportFolder = "";        % Pasta onde salvar os dados convertidos
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];
        end

        % Button pushed function: SelecionarPastaButton
        function SelecionarPastaButtonPushed(app, event)
            pasta = uigetdir('', 'Selecione a pasta onde os dados foram reduzidos');

            if pasta ~= 0
                app.selectedFolder = pasta;
                app.NenhumapastaselecionadaLabel.Text = pasta;
                app.StatusTextArea.Value = {'Pasta selecionada com sucesso.'};
            else
                app.NenhumapastaselecionadaLabel.Text = 'Nenhuma pasta selecionada';
                app.selectedFolder = "";
                app.StatusTextArea.Value = {'Seleção de pasta cancelada.'};
            end
        end

        % Button pushed function: SelecioneaPastaparaExportarButton
        function SelecioneaPastaparaExportarButtonPushed(app, event)
            pastaExport = uigetdir('', 'Selecione a pasta para salvar os dados convertidos');
            if pastaExport ~= 0
                app.exportFolder = pastaExport;
                app.NenhumapastaselecionadaLabel2.Text = pastaExport;  % <-- aqui atualiza a label
            else
                app.NenhumapastaselecionadaLabel2.Text = 'Nenhuma pasta selecionada';
            end
        end

        % Button pushed function: VoltarMenuButton
        function VoltarMenuButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            programas();          % Abre novamente a tela de menu
        end

        % Button pushed function: ConverterDadosButton
        function ConverterDadosButtonPushed(app, event)
            % --- Obter pasta base ---
            pastaBase = app.NenhumapastaselecionadaLabel.Text;
            if strcmp(pastaBase, 'Nenhuma pasta selecionada') || ~isfolder(pastaBase)
                uialert(app.UIFigure, 'Por favor, selecione a pasta contendo os dados antes de converter.', 'Erro');
                return;
            end
        
            % --- Definir extensão e sufixo da pasta ---
            estacao = app.SelecioneDropDown.Value;
            switch estacao
                case 'São José dos Campos (SJC)'
                    extensaoArquivo = 'SJC'; sufixoPasta = 'SJ'; nomeConcatenado = 'dados_concatenados_SJC.txt';
                case 'Araguatins (ARG)'
                    extensaoArquivo = 'ARG'; sufixoPasta = 'AR'; nomeConcatenado = 'dados_concatenados_ARG.txt';
                case 'Jataí (JAT)'
                    extensaoArquivo = 'JAT'; sufixoPasta = 'JA'; nomeConcatenado = 'dados_concatenados_JAT.txt';
            end
        
            pastaDestino = app.exportFolder;
            if isempty(app.exportFolder) || ~isfolder(app.exportFolder)
                uialert(app.UIFigure, 'Por favor, selecione a pasta para salvar os dados convertidos.', 'Erro');
                return;
            end
        
            app.StatusTextArea.Value = "Detectando estrutura de pastas...";
        
            % --- Detectar anos e meses ---
            anos = dir(fullfile(pastaBase, '20*')); anos = anos([anos.isdir]);
            if ~isempty(anos), pastaBase = fullfile(pastaBase, anos(1).name); end
        
            meses = dir(fullfile(pastaBase, '20* *')); meses = meses([meses.isdir]);
            if ~isempty(meses), pastaBase = fullfile(pastaBase, meses(1).name); end
        
            % --- Detectar pastas de dias ---
            dias = dir(fullfile(pastaBase, ['*' sufixoPasta]));
            dias = dias([dias.isdir]);
            if isempty(dias)
                app.StatusTextArea.Value = "Nenhuma pasta de dia (ex.: 170801SJ) encontrada.";
                return;
            end
        
            app.StatusTextArea.Value = "Pasta do mês detectada! Começando conversão...";
        
            % --- Inicializações ---
            totalConvertidos = 0;
            totalErros = 0;
            conteudoTotal = "";
            logs = {};
        
            formatadosDir = fullfile(pastaDestino, 'Formatados');
            if ~exist(formatadosDir, 'dir'), mkdir(formatadosDir); end
        
            % --- Loop sobre cada dia ---
            for i = 1:length(dias)
                pastaDia = fullfile(pastaBase, dias(i).name, 'Dados Reduzidos');
                if ~isfolder(pastaDia), continue; end
        
                % --- Arquivos do dia ---
                arquivos = dir(fullfile(pastaDia, ['*.' extensaoArquivo]));
                arquivos = arquivos(~contains({arquivos.name}, 'Filtro', 'IgnoreCase', true));
        
                for j = 1:length(arquivos)
                    try
                        arquivoOrigem = fullfile(pastaDia, arquivos(j).name);
        
                        % --- Passo 1: Converter para TXT ---
                        novoNome = erase(arquivos(j).name, ['.' extensaoArquivo]);
                        novoArquivo = fullfile(pastaDestino, [novoNome '.txt']);
                        copyfile(arquivoOrigem, novoArquivo);
        
                        % --- Passo 2: Formatar (se existir função) ---
                        if exist('formatarArquivo', 'file')
                            linhasFormatadas = formatarArquivo(novoArquivo);
                            nomeFormatado = fullfile(formatadosDir, ['formatado_' novoNome '.txt']);
                            fid = fopen(nomeFormatado, 'w');
                            fprintf(fid, 'yyyy.MM.dd (DDD) HH:mm:ss   foF2    h''F    hpF2\n');
                            fprintf(fid, '%s\n', linhasFormatadas{:});
                            fclose(fid);
                        else
                            nomeFormatado = novoArquivo;
                        end
        
                        % --- Passo 3: Corrigir horários faltantes SOMENTE se necessário ---
                        fid = fopen(nomeFormatado, 'r');
                        cabecalho = fgetl(fid);
                        dadosLinhas = textscan(fid, '%s', 'Delimiter', '\n');
                        fclose(fid);
                        
                        numLinhasDados = length(dadosLinhas{1}) - 1;  % desconta cabeçalho
                        
                        arquivoFoiCorrigido = false;  % flag para log
                        
                        if numLinhasDados < 288
                            % Só corrige se houver dados faltando
                            fid = fopen(nomeFormatado, 'r');
                            cabecalho = fgetl(fid);
                            dados = textscan(fid, '%s %s %s %s %s %s', 'Delimiter', ' ', 'MultipleDelimsAsOne', true);
                            fclose(fid);
                        
                            dataStr = dados{1}; dddStr = dados{2}; horaStr = dados{3};
                            foF2str = dados{4}; hFstr = dados{5}; hpF2str = dados{6};
                        
                            foF2 = str2double(foF2str); hF = str2double(hFstr); hpF2 = str2double(hpF2str);
                        
                            timestamp = datetime(strcat(dataStr, {' '}, horaStr), 'InputFormat','yyyy.MM.dd HH:mm:ss');
                            dataUnica = datetime(dataStr{1}, 'InputFormat','yyyy.MM.dd');
                            grade = (dataUnica:minutes(5):(dataUnica + hours(23) + minutes(55)))';
                        
                            foF2_full = nan(size(grade)); hF_full = nan(size(grade)); hpF2_full = nan(size(grade));
                        
                            [~, idx] = ismember(timestamp, grade);
                            for kidx = 1:length(idx)
                                if idx(kidx) ~= 0
                                    if ~isnan(foF2(kidx)), foF2_full(idx(kidx)) = foF2(kidx); end
                                    if ~isnan(hF(kidx)), hF_full(idx(kidx)) = hF(kidx); end
                                    if ~isnan(hpF2(kidx)), hpF2_full(idx(kidx)) = hpF2(kidx); end
                                end
                            end
                        
                            % Salvar arquivo corrigido apenas se necessário
                            novoArquivoCorrigido = fullfile(pastaDestino, [novoNome '_corrigido.txt']);
                            fid = fopen(novoArquivoCorrigido, 'w');
                            fprintf(fid, "%s\n", cabecalho);
                            for r = 1:length(grade)
                                fprintf(fid, '%s %s %s  %.3f   %.1f   %.1f\n', ...
                                    datestr(grade(r),'yyyy.mm.dd'), dddStr{1}, datestr(grade(r),'HH:MM:ss'), ...
                                    foF2_full(r), hF_full(r), hpF2_full(r));
                            end
                            fclose(fid);
                        
                            arquivoFoiCorrigido = true;  % marcar que houve correção
                        end
                        
                        % --- Usar arquivo corrigido ou original para concatenação ---
                        if arquivoFoiCorrigido
                            arquivoParaConcat = novoArquivoCorrigido;
                            logs{end+1} = "Arquivo corrigido: " + arquivos(j).name;
                        else
                            arquivoParaConcat = nomeFormatado;
                        end
                        
                        % --- Concatenar ---
                        fid = fopen(arquivoParaConcat, 'r');
                        cabecalho = fgetl(fid);
                        dados = textscan(fid, '%s', 'Delimiter', '\n');
                        fclose(fid);
                        
                        if isempty(conteudoTotal)
                            conteudoTotal = strjoin(dados{1}, newline);
                        else
                            conteudoTotal = conteudoTotal + newline + strjoin(dados{1}, newline);
                        end
                        
                        totalConvertidos = totalConvertidos + 1;
                        logs{end+1} = "Convertido e concatenado: " + arquivos(j).name;
        
                    catch ME
                        totalErros = totalErros + 1;
                        logs{end+1} = "Erro em " + arquivos(j).name + ": " + ME.message;
                    end
                end
            end
        
            % --- Salvar arquivo final ---
            arquivoFinal = fullfile(pastaDestino, nomeConcatenado);
            linhas = split(conteudoTotal, newline);
            linhas = linhas(~cellfun(@(x) all(isspace(x)), linhas));
            fid = fopen(arquivoFinal, 'w');
            fprintf(fid, 'yyyy.MM.dd (DDD) HH:mm:ss   foF2    h''F    hpF2\n%s', strjoin(linhas, newline));
            fclose(fid);
        
            logs{end+1} = "Arquivo concatenado salvo em: " + arquivoFinal;
            app.StatusTextArea.Value = cellstr(string(logs))';
        end

        % Button pushed function: ParametrosOMNIButton
        function ParametrosOMNIButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            parametros_omni();          % Abre novamente a dos parâmetros do omni
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.SelecionarPastaButton = uibutton(app.UIFigure, 'push');
            app.SelecionarPastaButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarPastaButtonPushed, true);
            app.SelecionarPastaButton.Text = 'Selecione a Pasta da Redução';
            app.PastaSelecionadaLabel = uilabel(app.UIFigure);
            app.PastaSelecionadaLabel.Text = 'Pasta Selecionada:';
            app.ConverterDadosButton = uibutton(app.UIFigure, 'push');
            app.ConverterDadosButton.ButtonPushedFcn = createCallbackFcn(app, @ConverterDadosButtonPushed, true);
            app.ConverterDadosButton.Text = 'Converter Dados';
            app.VoltarMenuButton = uibutton(app.UIFigure, 'push');
            app.VoltarMenuButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarMenuButtonPushed, true);
            app.VoltarMenuButton.Text = 'Voltar ao Menu dos Programas';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.SelecioneaPastaparaExportarButton = uibutton(app.UIFigure, 'push');
            app.SelecioneaPastaparaExportarButton.ButtonPushedFcn = createCallbackFcn(app, @SelecioneaPastaparaExportarButtonPushed, true);
            app.SelecioneaPastaparaExportarButton.Text = 'Selecione a Pasta para Exportar';
            app.SelecioneaestaoDropDownLabel = uilabel(app.UIFigure);
            app.SelecioneaestaoDropDownLabel.Text = 'Selecione a estação: ';
            app.SelecioneDropDown = uidropdown(app.UIFigure);
            app.SelecioneDropDown.Items = {'Araguatins (ARG)', 'Jataí (JAT)', 'São José dos Campos (SJC)'};
            app.SelecioneDropDown.Value = 'Araguatins (ARG)';
            app.StatusTextAreaLabel = uilabel(app.UIFigure);
            app.StatusTextAreaLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.UIFigure);
            app.StatusTextArea.Editable = 'off';
            app.NenhumapastaselecionadaLabel = uilabel(app.UIFigure);
            app.NenhumapastaselecionadaLabel.Text = 'Nenhuma pasta selecionada';
            app.PastaSelecionadaLabel2 = uilabel(app.UIFigure);
            app.PastaSelecionadaLabel2.Text = 'Pasta Selecionada:';
            app.NenhumapastaselecionadaLabel2 = uilabel(app.UIFigure);
            app.NenhumapastaselecionadaLabel2.Text = 'Nenhuma pasta selecionada';
            app.TratamentodosdadosdoUDIDALabel = uilabel(app.UIFigure);
            app.TratamentodosdadosdoUDIDALabel.Text = 'Tratamento dos dados do UDIDA';
            app.ParametrosOMNIButton = uibutton(app.UIFigure, 'push');
            app.ParametrosOMNIButton.ButtonPushedFcn = createCallbackFcn(app, @ParametrosOMNIButtonPushed, true);
            app.ParametrosOMNIButton.Text = 'Ir para os Parâmetros do OMNI';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = conversao_dados

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end