classdef main_menu < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        CinzaFundo                      matlab.ui.control.EditField
        CinzaClaroMenu                  matlab.ui.control.EditField
        ProgramasButton                 matlab.ui.control.Button
        ObservatoriosCadastradosButton  matlab.ui.control.Button
        ContatoButton                   matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];
        end

        % Button pushed function: ProgramasButton
        function ProgramasButtonPushed(app, event)
            programas();         % Abre a nova janela
            delete(app);         % Fecha esta janela
        end

        % Button pushed function: ObservatoriosCadastradosButton
        function ObservatoriosCadastradosButtonPushed(app, event)
            observatorios_cadastrados();
            delete(app);
        end

        % Callback function
        function TutorialButtonPushed(app, event)
            tutorial();
            delete(app);
        end

        % Button pushed function: ContatoButton
        function ContatoButtonPushed(app, event)
            contato();
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.CinzaFundo = uieditfield(app.UIFigure, 'text');
            app.CinzaFundo.Editable = 'off';
            app.CinzaClaroMenu = uieditfield(app.UIFigure, 'text');
            app.CinzaClaroMenu.Editable = 'off';
            app.CinzaClaroMenu.Value = '              Menu Principal';
            app.ProgramasButton = uibutton(app.UIFigure, 'push');
            app.ProgramasButton.ButtonPushedFcn = createCallbackFcn(app, @ProgramasButtonPushed, true);
            app.ProgramasButton.Text = 'Programas';
            app.ObservatoriosCadastradosButton = uibutton(app.UIFigure, 'push');
            app.ObservatoriosCadastradosButton.ButtonPushedFcn = createCallbackFcn(app, @ObservatoriosCadastradosButtonPushed, true);
            app.ObservatoriosCadastradosButton.Text = 'Observatórios Cadastrados';
            app.ContatoButton = uibutton(app.UIFigure, 'push');
            app.ContatoButton.ButtonPushedFcn = createCallbackFcn(app, @ContatoButtonPushed, true);
            app.ContatoButton.Text = 'Suporte';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main_menu

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end