classdef media_desvio < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VoltarMenuProgramasButton      matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        ArqParametrosLabel             matlab.ui.control.Label
        TratamentodeDadosdoOMNILabel   matlab.ui.control.Label
        PlotarGrafButton               matlab.ui.control.Button
        ArquivosSelecionadosEditField  matlab.ui.control.EditField
        SelecionarArqButton            matlab.ui.control.Button
        Passo1Label                    matlab.ui.control.Label
        Passo3Label                    matlab.ui.control.Label
        Passo2Label                    matlab.ui.control.Label
        InstrucoesLabel                matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];
        end

        % Button pushed function: SelecionarArqButton
        function SelecionarArqButtonPushed(app, event)
            [arquivos, caminho] = uigetfile('*.txt', 'Selecione os arquivos TXT', 'MultiSelect', 'on');

            if isequal(arquivos,0)
                uialert(app.UIFigure,'Nenhum arquivo selecionado.','Aviso');
                return;
            end
        
            if ischar(arquivos)
                arquivos = {arquivos};
            end
        
            filenames = fullfile(caminho, arquivos);
        
            % --------------------------
            % Cria nova janela para gráficos
            % --------------------------
            fig = uifigure('Name','resultados','Position',[100 100 800 600]);
            ax(1) = uiaxes(fig,'Position',[50 400 700 150]); % h'F
            ax(2) = uiaxes(fig,'Position',[50 225 700 150]); % f0F2
            ax(3) = uiaxes(fig,'Position',[50 50 700 150]);  % hmF2
        
            % --------------------------
            % Chama função que processa e plota
            % --------------------------
            resultados = processa_arquivos(filenames, ax);
        
            % --------------------------
            % Pergunta onde salvar
            % --------------------------
            [matfile, matpath] = uiputfile('*.mat','Salvar resultados como...');
            if ~isequal(matfile,0)
                save(fullfile(matpath, matfile), '-struct', 'resultados');
                uialert(app.UIFigure,'Resultados salvos com sucesso.','Sucesso');
            end
        end

        % Button pushed function: PlotarGrafButton
        function PlotarGrafButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            plotar_graficos();          % Abre a tela dos gráficos para plotar
        end

        % Button pushed function: VoltarMenuProgramasButton
        function VoltarMenuProgramasButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            programas();          % Abre novamente a tela de menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VoltarMenuProgramasButton = uibutton(app.UIFigure, 'push');
            app.VoltarMenuProgramasButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarMenuProgramasButtonPushed, true);
            app.VoltarMenuProgramasButton.Text = 'Voltar ao Menu dos Programas';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.ArqParametrosLabel = uilabel(app.UIFigure);
            app.ArqParametrosLabel.Text = 'Arquivos em .TXT:';
            app.TratamentodeDadosdoOMNILabel = uilabel(app.UIFigure);
            app.TratamentodeDadosdoOMNILabel.Text = 'Plotagem das Médias e dos Desvios Padrões';
            app.PlotarGrafButton = uibutton(app.UIFigure, 'push');
            app.PlotarGrafButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarGrafButtonPushed, true);
            app.PlotarGrafButton.Text = 'Ir para Plotagem dos Gráficos';
            app.ArquivosSelecionadosEditField = uieditfield(app.UIFigure, 'text');
            app.SelecionarArqButton = uibutton(app.UIFigure, 'push');
            app.SelecionarArqButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarArqButtonPushed, true);
            app.SelecionarArqButton.Text = 'Selecionar Arquivo';
            app.Passo1Label = uilabel(app.UIFigure);
            app.Passo1Label.Text = 'Passo 1: Selecione os arquivos (.txt) dos dias geomagneticamente calmos';
            app.Passo3Label = uilabel(app.UIFigure);
            app.Passo3Label.Text = 'Passo 3:  Salve o plot dos gráficos (opcional)';
            app.Passo2Label = uilabel(app.UIFigure);
            app.Passo2Label.Text = 'Passo 2: Salve o arquivo .mat no seu local de preferência';
            app.InstrucoesLabel = uilabel(app.UIFigure);
            app.InstrucoesLabel.Text = 'Instruções: ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = media_desvio

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end