classdef observatorios_cadastrados < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ObservatriosCadastradosLabel  matlab.ui.control.Label
        LatitudeLabel                 matlab.ui.control.Label
        LatitudeEditField             matlab.ui.control.EditField
        LongitudeLabel                matlab.ui.control.Label
        LongitudeEditField            matlab.ui.control.EditField
        NomeLabel                     matlab.ui.control.Label
        NomeEditField                 matlab.ui.control.EditField
        AltitudeLabel                 matlab.ui.control.Label
        AltitudeEditField             matlab.ui.control.EditField
        HoraLocalLabel                matlab.ui.control.Label
        HoraLocalEditField            matlab.ui.control.EditField
        RaiodoEquadorLabel            matlab.ui.control.Label
        RaiodoEquadorEditField        matlab.ui.control.EditField
        RaiodoPoloLabel               matlab.ui.control.Label
        RaiodoPoloEditField           matlab.ui.control.EditField
        ProximoButton                 matlab.ui.control.Button
        AnteriorButton                matlab.ui.control.Button
        AlterarButton                 matlab.ui.control.Button
        SalvarButton                  matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        VoltarMenuButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        observatorios  % seu array de structs com os dados
        indiceAtual = 1  % índice atual do observatório que está mostrando
        editavel = false % flag para habilitar/desabilitar edição
    end
   
    methods (Access = private)
        
        function atualizarCampos(app)
            obs = app.observatorios{app.indiceAtual};  % note o uso de {}
            app.NomeEditField.Value = obs.nome;
            app.LatitudeEditField.Value = num2str(obs.lat);
            app.LongitudeEditField.Value = num2str(obs.lon);
            app.AltitudeEditField.Value = num2str(obs.alt);
            app.HoraLocalEditField.Value = obs.hora_local;
            app.RaiodoEquadorEditField.Value = num2str(obs.raio_equador);
            app.RaiodoPoloEditField.Value = num2str(obs.raio_polo);
        end
        
        function setEditavel(app, flag)
            app.editavel = flag;
            % Habilita/desabilita edição dos campos
            app.NomeEditField.Editable = flag;
            app.LatitudeEditField.Editable = flag;
            app.LongitudeEditField.Editable = flag;
            app.AltitudeEditField.Editable = flag;
            app.HoraLocalEditField.Editable = flag;
            app.RaiodoEquadorEditField.Editable = flag;
            app.RaiodoPoloEditField.Editable = flag;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)
            try
                url = 'https://projeto-iolanda.onrender.com/observatorios';
                options = weboptions('ContentType', 'json');
                dados = webread(url, options);
        
        
                for i = 1:numel(dados)
                    obs = dados(i);
        
                    % Preenche valores com checagem de campos opcionais
                    if isfield(obs, 'hora_local')
                        hora_local = obs.hora_local;
                    else
                        hora_local = '';
                    end
        
                    if isfield(obs, 'raio_equador')
                        raio_equador = obs.raio_equador;
                    else
                        raio_equador = 0;
                    end
        
                    if isfield(obs, 'raio_polo')
                        raio_polo = obs.raio_polo;
                    else
                        raio_polo = 0;
                    end
        
                    obsStruct = struct( ...
                        'nome', obs.nome, ...
                        'lat', obs.localizacao.lat, ...
                        'lon', obs.localizacao.lon, ...
                        'alt', obs.localizacao.alt, ...
                        'hora_local', hora_local, ...
                        'raio_equador', raio_equador, ...
                        'raio_polo', raio_polo ...
                    );
        
                    app.observatorios{i} = obsStruct;  % preenche cell array
                end
        
                app.indiceAtual = 1;
                app.atualizarCampos();
        
            catch ME
                uialert(app.UIFigure, ['Erro ao buscar dados: ' ME.message], 'Erro de conexão');
            end
            
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];
        end

        % Button pushed function: ProximoButton
        function ProximoButtonPushed(app, event)
            if app.indiceAtual < length(app.observatorios)
                app.indiceAtual = app.indiceAtual + 1;
            else 
                app.indiceAtual = 1; % Volta para o primeiro (carrossel)
            end
            app.atualizarCampos();
        end

        % Button pushed function: AnteriorButton
        function AnteriorButtonPushed(app, event)
            if app.indiceAtual > 1
                app.indiceAtual = app.indiceAtual - 1;
            else
                app.indiceAtual = length(app.observatorios); % Volta para o último
            end
            app.atualizarCampos();
        end

        % Button pushed function: AlterarButton
        function AlterarButtonPushed(app, event)
          app.setEditavel(true);
        end

        % Button pushed function: SalvarButton
        function SalvarButtonPushed(app, event)
            if app.editavel
            % Atualiza os dados da struct com os valores atuais das textboxes
            obs = struct();
            obs.nome = app.NomeEditField.Value;
            obs.lat = str2double(app.LatitudeEditField.Value);
            obs.lon = str2double(app.LongitudeEditField.Value);
            obs.alt = str2double(app.AltitudeEditField.Value);
            obs.hora_local = app.HoraLocalEditField.Value;
            obs.raio_equador = str2double(app.RaioEquadorEditField.Value);
            obs.raio_polo = str2double(app.RaioPoloEditField.Value);

            % Validação simples
            if isnan(obs.lat) || isnan(obs.lon) || isnan(obs.alt) || isnan(obs.raio_equador) || isnan(obs.raio_polo)
                uialert(app.UIFigure,'Por favor, insira valores numéricos válidos.','Erro');
                return;
            end

            % Atualiza o array
            app.observatorios{app.indiceAtual} = obs;
            app.setEditavel(false);

            % Aqui você pode chamar sua API para salvar no banco, ex:
            % data = jsonencode(obs);
            % webwrite('https://seu_endpoint/observatorios/nome/<nome>', data, weboptions('MediaType','application/json','RequestMethod','put'));

            uialert(app.UIFigure, 'Dados salvos com sucesso!', 'Sucesso');
            end
        end

        % Button pushed function: VoltarMenuButton
        function VoltarMenuButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            main_menu();          % Abre novamente a tela de menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ObservatriosCadastradosLabel = uilabel(app.UIFigure);
            app.ObservatriosCadastradosLabel.Text = 'Observatórios Cadastrados';
            app.LatitudeLabel = uilabel(app.UIFigure);
            app.LatitudeLabel.Text = 'Latitude:';
            app.LatitudeEditField = uieditfield(app.UIFigure, 'text');
            app.LongitudeLabel = uilabel(app.UIFigure);
            app.LongitudeLabel.Text = 'Longitude:';
            app.LongitudeEditField = uieditfield(app.UIFigure, 'text');
            app.NomeLabel = uilabel(app.UIFigure);
            app.NomeLabel.Text = {'Nome:'; ''};
            app.NomeEditField = uieditfield(app.UIFigure, 'text');
            app.AltitudeLabel = uilabel(app.UIFigure);
            app.AltitudeLabel.Text = 'Altitude:';
            app.AltitudeEditField = uieditfield(app.UIFigure, 'text');
            app.HoraLocalLabel = uilabel(app.UIFigure);
            app.HoraLocalLabel.Text = 'Hora Local:';
            app.HoraLocalEditField = uieditfield(app.UIFigure, 'text');
            app.RaiodoEquadorLabel = uilabel(app.UIFigure);
            app.RaiodoEquadorLabel.Text = 'Raio do Equador: ';
            app.RaiodoEquadorEditField = uieditfield(app.UIFigure, 'text');
            app.RaiodoPoloLabel = uilabel(app.UIFigure);
            app.RaiodoPoloLabel.Text = 'Raio do Polo:';
            app.RaiodoPoloEditField = uieditfield(app.UIFigure, 'text');
            app.ProximoButton = uibutton(app.UIFigure, 'push');
            app.ProximoButton.ButtonPushedFcn = createCallbackFcn(app, @ProximoButtonPushed, true);
            app.ProximoButton.Text = 'Próximo';
            app.AnteriorButton = uibutton(app.UIFigure, 'push');
            app.AnteriorButton.ButtonPushedFcn = createCallbackFcn(app, @AnteriorButtonPushed, true);
            app.AnteriorButton.Text = 'Anterior';
            app.AlterarButton = uibutton(app.UIFigure, 'push');
            app.AlterarButton.ButtonPushedFcn = createCallbackFcn(app, @AlterarButtonPushed, true);
            app.AlterarButton.Text = 'Alterar';
            app.SalvarButton = uibutton(app.UIFigure, 'push');
            app.SalvarButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarButtonPushed, true);
            app.SalvarButton.Text = 'Salvar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.VoltarMenuButton = uibutton(app.UIFigure, 'push');
            app.VoltarMenuButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarMenuButtonPushed, true);
            app.VoltarMenuButton.Text = 'Voltar ao Menu Principal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = observatorios_cadastrados

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end