classdef parametros_omni < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Passo1Label                   matlab.ui.control.Label
        AcessarSiteButton             matlab.ui.control.Button
        VoltarMenuProgramasButton     matlab.ui.control.Button
        Image                         matlab.ui.control.Image
        ArqParametrosLabel            matlab.ui.control.Label
        Passo3Label                   matlab.ui.control.Label
        Passo2Label                   matlab.ui.control.Label
        Passo4Label                   matlab.ui.control.Label
        TratamentodeDadosdoOMNILabel  matlab.ui.control.Label
        MediaDesvioButton             matlab.ui.control.Button
        InstrucoesLabel               matlab.ui.control.Label
        ArqParametrosEditField        matlab.ui.control.EditField
        SelecionarArqParamButton      matlab.ui.control.Button
        ArqDadosLabel                 matlab.ui.control.Label
        ArqDadosEditField             matlab.ui.control.EditField
        SelecionarArqDadosButton      matlab.ui.control.Button
        SalvarDadosTratadosButton     matlab.ui.control.Button
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];
        end

        % Callback function
        function VoltarMenuButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            programas();          % Abre novamente a tela de menu
        end

        % Button pushed function: AcessarSiteButton
        function AcessarSiteButtonPushed(app, event)
            web('https://omniweb.gsfc.nasa.gov/form/omni_min.html','-browser');
        end

        % Button pushed function: SelecionarArqParamButton
        function SelecionarArqParamButtonPushed(app, event)
            % Janela para escolher arquivo
            [f,p] = uigetfile({'*.txt','Arquivos TXT (*.txt)'}, ...
                              'Selecione o arquivo de PARÂMETROS');
        
            % Se o usuário não cancelou
            if f ~= 0
                headerFile = fullfile(p,f);
        
            % Envia o caminho para o campo EditField da interface
            app.ArqParametrosEditField.Value = headerFile;
       
            end
        end

        % Button pushed function: SelecionarArqDadosButton
        function SelecionarArqDadosButtonPushed(app, event)
            % Seleção do arquivo
            [f,p] = uigetfile({'*.txt','Arquivos TXT (*.txt)'}, ...
                              'Selecione o arquivo de DADOS');
        
            % Se o usuário não cancelou
            if f ~= 0
                dataFile = fullfile(p,f);
        
                % Atualiza o campo de texto da interface
                app.ArqDadosEditField.Value = dataFile;
            end
        end

        % Button pushed function: SalvarDadosTratadosButton
        function SalvarDadosTratadosButtonPushed(app, event)
            % ============================================================
            % VERIFICAÇÃO INICIAL
            % ============================================================
            headerFile = app.ArqParametrosEditField.Value;
            dataFile   = app.ArqDadosEditField.Value;
            
            if isempty(headerFile) || isempty(dataFile)
                uialert(app.UIFigure,'Selecione os dois arquivos TXT antes de continuar.','Erro');
                return;
            end
            
            try
                % ============================================================
                % 1) LER ARQUIVO DE PARÂMETROS / CABEÇALHO
                % ============================================================
                rawHeader = fileread(headerFile);
                lines = splitlines(rawHeader);
                
                colNames = {};
                colIndex = [];
                
                for i = 1:numel(lines)
                    line = strtrim(lines{i});
                    if isempty(line), continue; end
                    
                    C = strsplit(line);
                    
                    % linha válida?
                    if ~isempty(regexp(C{1}, '^\d+$','once'))
                        idx = str2double(C{1});
                        fullName = strtrim(strjoin(C(2:end),' '));
                        fullLower = lower(fullName);
                        
                        % ----------------------------------------------------
                        % Pequeno mapeamento direto (sem função)
                        % ----------------------------------------------------
                        if contains(fullLower,'year')
                            ab = 'Year';
                        elseif contains(fullLower,'day')
                            ab = 'Day';
                        elseif contains(fullLower,'hour')
                            ab = 'Hour';
                        elseif contains(fullLower,'minute')
                            ab = 'Min';
                        elseif contains(fullLower,'field magnitude')
                            ab = 'Fma';
                        elseif contains(fullLower,'bx')
                            ab = 'Bx';
                        elseif contains(fullLower,'by') && contains(fullLower,'gsm')
                            ab = 'ByG';
                        elseif contains(fullLower,'bz') && contains(fullLower,'gsm')
                            ab = 'BzG';
                        elseif contains(fullLower,'by') && contains(fullLower,'gse')
                            ab = 'ByE';
                        elseif contains(fullLower,'bz') && contains(fullLower,'gse')
                            ab = 'BzE';
                        elseif contains(fullLower,'flow speed')
                            ab = 'Spe';
                        elseif contains(fullLower,'velocity') && contains(fullLower,'vx')
                            ab = 'Vx';
                        elseif contains(fullLower,'velocity') && contains(fullLower,'vy')
                            ab = 'Vy';
                        elseif contains(fullLower,'velocity') && contains(fullLower,'vz')
                            ab = 'Vz';
                        elseif contains(fullLower,'proton density')
                            ab = 'Den';
                        elseif contains(fullLower,'proton temperature')
                            ab = 'Pro';
                        elseif contains(fullLower,'flow pressure')
                            ab = 'Pre';
                        elseif contains(fullLower,'electric field')
                            ab = 'Ey';
                        elseif contains(fullLower,'plasma beta')
                            ab = 'Bet';
                        elseif contains(fullLower,'alfven mach')
                            ab = 'Amn';
                        elseif contains(fullLower,'magnetosonic mach')
                            ab = 'Mmn';
                        elseif contains(fullLower,'ae index')
                            ab = 'AE';
                        elseif contains(fullLower,'al index')
                            ab = 'AL';
                        elseif contains(fullLower,'au index')
                            ab = 'AU';
                        elseif contains(fullLower,'sym/d')
                            ab = 'SYMd';
                        elseif contains(fullLower,'sym/h')
                            ab = 'SYMh';
                        elseif contains(fullLower,'asy/d')
                            ab = 'ASYd';
                        elseif contains(fullLower,'asy/h')
                            ab = 'ASYh';
                        elseif contains(fullLower,'polar cap') || contains(fullLower,'pc index')
                            ab = 'PC';
                            
                        else
                            % fallback simples
                            tokens = regexp(fullName,'[A-Za-z]+','match');
                            if isempty(tokens)
                                ab = sprintf('C%02d', idx);
                            else
                                base = tokens{1};
                                ab = upper(base(1:min(3,end)));
                            end
                        end
                        
                        colIndex(end+1) = idx;
                        colNames{end+1} = ab;
                    end
                end
                
                % ============================================================
                % 2) LER ARQUIVO DE DADOS DO OMNI — CORRIGIDO
                % ============================================================
                fid = fopen(dataFile);
                
                fmt = repmat('%f',1,max(colIndex));
                raw = textscan(fid, fmt, 'Delimiter', {' ','\t'}, 'MultipleDelimsAsOne', true);
                fclose(fid);
                
                % Verificar tamanhos (debug opcional)
                %                 for k = 1:numel(raw)
                %                     fprintf('Coluna %d tem %d linhas\n', k, numel(raw{k}));
                %                 end
                
                % Garantir tamanho igual em todas as colunas
                nRows = max(cellfun(@numel, raw));
                nCols = numel(colIndex);
                
                M = NaN(nRows, nCols);  % inicia matriz completa
                
                for c = 1:nCols
                    if ~isempty(raw{c})
                        M(1:numel(raw{c}), c) = raw{c};
                    end
                end
                
                % ============================================================
                % 3) CORRIGIR VALORES INVÁLIDOS (99999 etc.)
                % ============================================================
                for ii = 1:numel(M)
                    val = M(ii);
                    if isnan(val), continue; end
                    
                    txt = regexprep(sprintf('%.3f', val), '[0.]','');
                    if ~isempty(txt) && all(txt=='9')
                        M(ii) = NaN;
                    end
                end
                
                % ============================================================
                % 4) CONVERTER MATRIZ PARA STRINGS
                % ============================================================
                nRows = size(M,1);
                nCols = size(M,2);
                strData = cell(nRows, nCols);
                
                for i = 1:nRows
                    for j = 1:nCols
                        val = M(i,j);
                        
                        if isnan(val)
                            strData{i,j} = 'NaN';
                            continue;
                        end
                        
                        if j <= 4
                            strData{i,j} = sprintf('%d', round(val));
                            continue;
                        end
                        
                        nomeCol = colNames{j};
                        isIndice = any(strcmpi(nomeCol, {'AE','AL','AU','SYMD','SYMH','ASYD','ASYH','PC','DST'}));
                        
                        if isIndice
                            strData{i,j} = sprintf('%d', round(val));
                        else
                            if mod(val,1) ~= 0
                                strData{i,j} = num2str(val);
                            else
                                if abs(val) >= 1000
                                    strData{i,j} = sprintf('%.1f', val);
                                else
                                    strData{i,j} = sprintf('%d', round(val));
                                end
                            end
                        end
                    end
                end
                
                % ============================================================
                % 5) CALCULAR LARGURA DE CADA COLUNA
                % ============================================================
                colWidths = zeros(1,nCols);
                
                for j = 1:nCols
                    lengths = cellfun(@length, strData(:,j));
                    colWidths(j) = max(max(lengths), length(colNames{j})) + 2;
                end
                
                % ============================================================
                % 6) GERAR ARQUIVO TRATADO
                % ============================================================
                [p,n,~] = fileparts(dataFile);          % p = pasta, n = nome sem extensão
                
                % Evita duplicar "omni" no nome
                if startsWith(lower(n),'omni')
                    baseName = n;
                else
                    baseName = ['omni' n];
                end
                
                newFileName = [baseName '_tratados.txt'];  % monta nome novo
                newFile = fullfile(p, newFileName);
                
                fid = fopen(newFile,'w');
                
                % Cabeçalho
                for j = 1:nCols
                    fprintf(fid, sprintf('%%-%ds', colWidths(j)), colNames{j});
                end
                fprintf(fid,'\n');
                
                % Dados
                for i = 1:nRows
                    for j = 1:nCols
                        fprintf(fid, sprintf('%%-%ds', colWidths(j)), strData{i,j});
                    end
                    fprintf(fid,'\n');
                end
                
                fclose(fid);
                
                % ============================================================
                % FINAL — mensagem bonitinha
                % ============================================================
                msg = sprintf(['Arquivo tratado criado com sucesso!\n\n', ...
                    'Nome do arquivo: %s\n', ...
                    'Localização: %s'], newFileName, p);
                
                uialert(app.UIFigure, msg, 'Sucesso', 'Icon', 'info');
                
                % Abre o arquivo no editor
                edit(newFile);
                
            catch ME
                uialert(app.UIFigure, ['Erro ao processar os arquivos: ' ME.message], 'Erro');
            end
        end

        % Button pushed function: MediaDesvioButton
        function MediaDesvioButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            media_desvio();          % Abre novamente a tela de medias e desvios padrões
        end

        % Button pushed function: VoltarMenuProgramasButton
        function VoltarMenuProgramasButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            programas();          % Abre novamente a tela de menu
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Passo1Label = uilabel(app.UIFigure);
            app.Passo1Label.Text = 'Passo 1: Acesse o site do OMNI e gere os arquivos';
            app.AcessarSiteButton = uibutton(app.UIFigure, 'push');
            app.AcessarSiteButton.ButtonPushedFcn = createCallbackFcn(app, @AcessarSiteButtonPushed, true);
            app.AcessarSiteButton.Text = 'Acessar Site';
            app.VoltarMenuProgramasButton = uibutton(app.UIFigure, 'push');
            app.VoltarMenuProgramasButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarMenuProgramasButtonPushed, true);
            app.VoltarMenuProgramasButton.Text = 'Voltar ao Menu dos Programas';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.ArqParametrosLabel = uilabel(app.UIFigure);
            app.ArqParametrosLabel.Text = 'Arquivo dos PARÂMETROS:';
            app.Passo3Label = uilabel(app.UIFigure);
            app.Passo3Label.Text = 'Passo 3:  Baixe o TXT dos DADOS';
            app.Passo2Label = uilabel(app.UIFigure);
            app.Passo2Label.Text = 'Passo 2: Baixe o TXT de PARÂMETROS (Create ASCII File)';
            app.Passo4Label = uilabel(app.UIFigure);
            app.Passo4Label.Text = 'Passo 4: Selecione os dois arquivos abaixo';
            app.TratamentodeDadosdoOMNILabel = uilabel(app.UIFigure);
            app.TratamentodeDadosdoOMNILabel.Text = 'Importação e Tratamento dos Dados do OMNI';
            app.MediaDesvioButton = uibutton(app.UIFigure, 'push');
            app.MediaDesvioButton.ButtonPushedFcn = createCallbackFcn(app, @MediaDesvioButtonPushed, true);
            app.MediaDesvioButton.Text = 'Ir para Média e Desvio Padrão';
            app.InstrucoesLabel = uilabel(app.UIFigure);
            app.InstrucoesLabel.Text = 'Instruções: ';
            app.ArqParametrosEditField = uieditfield(app.UIFigure, 'text');
            app.SelecionarArqParamButton = uibutton(app.UIFigure, 'push');
            app.SelecionarArqParamButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarArqParamButtonPushed, true);
            app.SelecionarArqParamButton.Text = 'Selecionar Arquivo';
            app.ArqDadosLabel = uilabel(app.UIFigure);
            app.ArqDadosLabel.Text = 'Arquivo dos DADOS:';
            app.ArqDadosEditField = uieditfield(app.UIFigure, 'text');
            app.SelecionarArqDadosButton = uibutton(app.UIFigure, 'push');
            app.SelecionarArqDadosButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarArqDadosButtonPushed, true);
            app.SelecionarArqDadosButton.Text = 'Selecionar Arquivo';
            app.SalvarDadosTratadosButton = uibutton(app.UIFigure, 'push');
            app.SalvarDadosTratadosButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarDadosTratadosButtonPushed, true);
            app.SalvarDadosTratadosButton.Text = 'Salvar Dados Tratados';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = parametros_omni

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end