classdef programas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        CinzaFundo                 matlab.ui.control.EditField
        CinzaClaroMenu             matlab.ui.control.EditField
        ConversodosDadosButton     matlab.ui.control.Button
        ParamOMNIButton            matlab.ui.control.Button
        PlotagemdosGraficosButton  matlab.ui.control.Button
        MediaDesvioButton          matlab.ui.control.Button
        VoltarMenuButton           matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];
        end

        % Button pushed function: ConversodosDadosButton
        function ConversodosDadosButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            conversao_dados();          % Abre novamente a tela de conversão dos dados do udida
        end

        % Button pushed function: ParamOMNIButton
        function ParamOMNIButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            parametros_omni();          % Abre novamente a dos parâmetros do omni
        end

        % Button pushed function: MediaDesvioButton
        function MediaDesvioButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            media_desvio();          % Abre novamente a tela de medias e desvios padrões
        end

        % Button pushed function: PlotagemdosGraficosButton
        function PlotagemdosGraficosButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            plotar_graficos();          % Abre novamente a tela de medias e desvios padrões
        end

        % Button pushed function: VoltarMenuButton
        function VoltarMenuButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual (cadastro)
            main_menu();          % Abre novamente a tela de menu 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.CinzaFundo = uieditfield(app.UIFigure, 'text');
            app.CinzaFundo.Editable = 'off';
            app.CinzaClaroMenu = uieditfield(app.UIFigure, 'text');
            app.CinzaClaroMenu.Editable = 'off';
            app.CinzaClaroMenu.Value = '           Menu dos Programas';
            app.ConversodosDadosButton = uibutton(app.UIFigure, 'push');
            app.ConversodosDadosButton.ButtonPushedFcn = createCallbackFcn(app, @ConversodosDadosButtonPushed, true);
            app.ConversodosDadosButton.Text = 'Conversão dos Dados';
            app.ParamOMNIButton = uibutton(app.UIFigure, 'push');
            app.ParamOMNIButton.ButtonPushedFcn = createCallbackFcn(app, @ParamOMNIButtonPushed, true);
            app.ParamOMNIButton.Text = 'Parâmetros OMNI';
            app.PlotagemdosGraficosButton = uibutton(app.UIFigure, 'push');
            app.PlotagemdosGraficosButton.ButtonPushedFcn = createCallbackFcn(app, @PlotagemdosGraficosButtonPushed, true);
            app.PlotagemdosGraficosButton.Text = 'Plotagem dos Gráficos';
            app.MediaDesvioButton = uibutton(app.UIFigure, 'push');
            app.MediaDesvioButton.ButtonPushedFcn = createCallbackFcn(app, @MediaDesvioButtonPushed, true);
            app.MediaDesvioButton.Text = 'Média e Desvio Padrão';
            app.VoltarMenuButton = uibutton(app.UIFigure, 'push');
            app.VoltarMenuButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarMenuButtonPushed, true);
            app.VoltarMenuButton.Text = 'Voltar ao Menu Principal';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = programas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end