classdef tela_login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        EmailLabel          matlab.ui.control.Label
        EmailEditField      matlab.ui.control.EditField
        SenhaLabel          matlab.ui.control.Label
        SenhaPasswordField  matlab.ui.control.EditField
        LoginButton         matlab.ui.control.Button
        CadastrarButton     matlab.ui.control.Button
        Image               matlab.ui.control.Image
        ProblemasButton     matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IOLANDA';
            app.UIFigure.Resize = 'off';  % Impede maximizar e redimensionar
            % Obtém o tamanho da tela
            screenSize = get(0,'ScreenSize');  % [x y largura altura]
            
            % Define o tamanho da janela do app
            appWidth = 800;
            appHeight = 600;
            
            % Calcula a posição para centralizar
            xPos = (screenSize(3) - appWidth)/2;
            yPos = (screenSize(4) - appHeight)/2;
            
            % Ajusta a posição da UIFigure
            app.UIFigure.Position = [xPos yPos appWidth appHeight];
        end

        % Button pushed function: CadastrarButton
        function CadastrarButtonPushed(app, event)
            cadastrar_usuario(); % cria uma instância da segunda app
            delete(app.UIFigure);  % Fecha a tela atual (cadastro)
        end

        % Button pushed function: LoginButton
        function LoginButtonPushed(app, event)
            emailDigitado = app.EmailEditField.Value;     % Campo de email
            senhaDigitada = app.SenhaPasswordField.Value;   % Campo de senha
       
        
        % Verifica se algum campo está vazio
        if isempty(emailDigitado) || isempty(senhaDigitada)
            uialert(app.UIFigure, 'Por favor, preencha todos os campos.', 'Erro');
            return;
        end
        
        % Validação simples do email: verificar se tem '@' e '.'
        if ~contains(emailDigitado, '@') || ~contains(emailDigitado, '.')
            uialert(app.UIFigure, 'Por favor, insira um email válido.', 'Erro');
            return;
        end
        
        
        url = 'https://projeto-iolanda.onrender.com/login';  % URL da API
    
        data = struct('email', emailDigitado, 'senha', senhaDigitada);
    
        options = weboptions('MediaType', 'application/json');
        
        try
        resposta = webwrite(url, data, options);
        if isfield(resposta, 'status') && strcmp(resposta.status, 'ok')
            uialert(app.UIFigure, 'Login realizado com sucesso!', 'Sucesso');
            main_menu();             
            delete(app.UIFigure);  % Fecha a tela atual (login)
        else
            if isfield(resposta, 'mensagem')
                uialert(app.UIFigure, resposta.mensagem, 'Erro');
            else
                uialert(app.UIFigure, 'Erro no login', 'Erro');
            end
        end
        catch
        %uialert(app.UIFigure, 'Não foi possível conectar ao servidor.', 'Erro');
        end
        
        % Aqui você pode abrir a próxima interface ou continuar fluxo end
        end

        % Button pushed function: ProblemasButton
        function ProblemasButtonPushed(app, event)
            contato_login(); % cria uma instância da segunda app
            delete(app.UIFigure);  % Fecha a tela atual (cadastro)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.EmailLabel = uilabel(app.UIFigure);
            app.EmailLabel.Text = {'Email:'; ''};
            app.EmailEditField = uieditfield(app.UIFigure, 'text');
            app.SenhaLabel = uilabel(app.UIFigure);
            app.SenhaLabel.Text = {'Senha:'; ''};
            app.SenhaPasswordField = uieditfield(app.UIFigure, 'text');
            app.LoginButton = uibutton(app.UIFigure, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @LoginButtonPushed, true);
            app.LoginButton.Text = {'Login'; ''};
            app.CadastrarButton = uibutton(app.UIFigure, 'push');
            app.CadastrarButton.ButtonPushedFcn = createCallbackFcn(app, @CadastrarButtonPushed, true);
            app.CadastrarButton.Text = 'Cadastrar';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.ProblemasButton = uibutton(app.UIFigure, 'push');
            app.ProblemasButton.ButtonPushedFcn = createCallbackFcn(app, @ProblemasButtonPushed, true);
            app.ProblemasButton.Text = 'Problemas com o Login?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tela_login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end