classdef plotagem_graficos < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        VoltarMenuButton               matlab.ui.control.Button
        NomedoRedutorEditFieldLabel    matlab.ui.control.Label
        SelecioneaestaoDropDownLabel   matlab.ui.control.Label
        SelecionePastaButton           matlab.ui.control.Button
        SelecioneoperododeanliseLabel  matlab.ui.control.Label
        MsDropDownLabel                matlab.ui.control.Label
        MesDropDown                    matlab.ui.control.DropDown
        DataInicialEditFieldLabel      matlab.ui.control.Label
        DataInicialEditField           matlab.ui.control.NumericEditField
        AnoEditFieldLabel              matlab.ui.control.Label
        AnoEditField                   matlab.ui.control.NumericEditField
        OMNISelecionadoEditField       matlab.ui.control.EditField
        OMNIButton                     matlab.ui.control.Button
        MediaSelecionadaEditField      matlab.ui.control.EditField
        MediaButton                    matlab.ui.control.Button
        DataFinalEditFieldLabel        matlab.ui.control.Label
        DataFinalEditField             matlab.ui.control.NumericEditField
        PastaSelecionadaEditField      matlab.ui.control.EditField
        SelecioneDropDown              matlab.ui.control.DropDown
        NomedoRedutorEditField         matlab.ui.control.EditField
        PlotarGraficoButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        selecionados % Description
        nomeRedutor = '';
        diaInicial
        diaFinal
        mesSelecionado
        anoDetectado
        filenameOmni
        filenameMat
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelecioneDropDown
        function SelecioneDropDownValueChanged(app, event)
            value = app.SelecioneDropDown.Value;
            app.estacaoSelecionada = value;       % Guardamos só o nome da estação
        end

        % Button pushed function: SelecionePastaButton
        function SelecionePastaButtonPushed(app, event)
            [arquivos, caminho] = uigetfile( ...
                {'*.txt', 'Arquivos de texto (*.txt)'}, ...
                'Selecione 1 a 3 arquivos da estação', ...
                'MultiSelect', 'on');
        
            if isequal(arquivos,0)
                return;
            end
        
            % Se o usuário escolheu apenas 1 arquivo, transformamos em cell
            if ischar(arquivos)
                arquivos = {arquivos};
            end
        
            % Garante no máximo 3 arquivos
            if numel(arquivos) > 3
                uialert(app.UIFigure, ...
                    'Selecione no máximo 3 arquivos (E01, E02, E03).', ...
                    'Erro');
                return;
            end
        
            % Monta caminhos completos
            app.selecionados = fullfile(caminho, arquivos{:});
        
            % Exibe no EditField
            app.PastaSelecionadaEditField.Value = caminho;
        
            disp("Arquivos selecionados:");
            disp(app.selecionados);
        end

        % Value changed function: NomedoRedutorEditField
        function NomedoRedutorEditFieldValueChanged(app, event)
            app.nomeRedutor = app.NomedoRedutorEditField.Value;
            disp(['Nome do redutor definido: ', app.nomeRedutor]);
        end

        % Value changed function: DataInicialEditField
        function DataInicialEditFieldValueChanged(app, event)
            app.diaInicial = str2double(app.DataInicialEditField.Value); % converte para número  
        end

        % Value changed function: DataFinalEditField
        function DataFinalEditFieldValueChanged(app, event)
            app.diaFinal = str2double(app.DataFinalEditField.Value);
        end

        % Value changed function: MesDropDown
        function MesDropDownValueChanged(app, event)
            meses = {'Janeiro','Fevereiro','Março','Abril','Maio','Junho', ...
             'Julho','Agosto','Setembro','Outubro','Novembro','Dezembro'};
            app.mesSelecionado = find(strcmp(app.MesDropDown.Value, meses));
        end

        % Value changed function: AnoEditField
        function AnoEditFieldValueChanged(app, event)
           app.anoDetectado = str2double(app.AnoEditField.Value);
        end

        % Button pushed function: OMNIButton
        function OMNIButtonPushed(app, event)
            [file, path] = uigetfile({'*.txt','Arquivos OMNI (*.txt)'}, 'Selecione o arquivo OMNI do mesmo período');
            if isequal(file,0)
                uialert(app.UIFigure, 'Nenhum arquivo OMNI selecionado.', 'Erro');
                return;
            end
            app.filenameOmni = fullfile(path, file);
            app.OMNISelecionadoEditField.Value = app.filenameOmni;  % exibe no campo de texto
        end

        % Button pushed function: MediaButton
        function MediaButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat','Arquivos MAT (*.mat)'}, 'Selecione o arquivo mediasedesvios.mat');
            if isequal(file,0)
                uialert(app.UIFigure, 'Nenhum arquivo MAT selecionado.', 'Erro');
                return;
            end
            app.filenameMat = fullfile(path, file);
            app.MediaSelecionadaEditField.Value = app.filenameMat;  % exibe no campo de texto
        end

        % Button pushed function: PlotarGraficoButton
        function PlotarGraficoButtonPushed(app, event)
            % ================================
            % 1. Ler valores da interface
            % ================================
            pasta = app.PastaSelecionadaEditField.Value;
            app.nomeRedutor = app.NomedoRedutorEditField.Value;
        
            app.mesSelecionado = str2double(app.MesDropDown.Value);
            app.diaInicial     = str2double(app.DataInicialEditField.Value);
            app.diaFinal       = str2double(app.DataFinalEditField.Value);
            app.anoDetectado   = str2double(app.AnoEditField.Value);
        
            % ================================
            % 2. Validar pasta e arquivos
            % ================================
            arquivos = dir(fullfile(pasta, '*.txt'));
        
            if isempty(arquivos)
                uialert(app.UIFigure, ...
                    'Nenhum arquivo .txt encontrado nessa pasta.', 'Erro');
                return;
            end
        
            % Salvar lista de caminhos completos
            app.selecionados = fullfile({arquivos.folder}, {arquivos.name});
        
            % ================================
            % 3. Validar arquivos OMNI e Média
            % ================================
            if isempty(app.filenameOmni)
                uialert(app.UIFigure, 'Selecione o arquivo OMNI.', 'Erro');
                return;
            end
            
            if isempty(app.filenameMat)
                uialert(app.UIFigure, 'Selecione o arquivo de Médias.', 'Erro');
                return;
            end
        
            % ================================
            % 4. Chamar a função externa (processarPlot.m)
            % ================================
            try
                processarPlot( ...
                    app.selecionados, ...
                    app.filenameOmni, ...
                    app.filenameMat, ...
                    app.mesSelecionado, ...
                    app.diaInicial, ...
                    app.diaFinal, ...
                    app.anoDetectado, ...
                    app.nomeRedutor );
                
            catch ME
                uialert(app.UIFigure, ...
                    ['Erro ao processar/plotar os dados:' newline ME.message], ...
                    'Erro');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.VoltarMenuButton = uibutton(app.UIFigure, 'push');
            app.VoltarMenuButton.Text = 'Voltar ao Menu';
            app.NomedoRedutorEditFieldLabel = uilabel(app.UIFigure);
            app.NomedoRedutorEditFieldLabel.Text = 'Nome do Redutor:';
            app.NomedoRedutorEditField = uieditfield(app.UIFigure, 'text');
            app.NomedoRedutorEditField.ValueChangedFcn = createCallbackFcn(app, @NomedoRedutorEditFieldValueChanged, true);
            app.SelecioneaestaoDropDownLabel = uilabel(app.UIFigure);
            app.SelecioneaestaoDropDownLabel.Text = 'Selecione a estação: ';
            app.SelecioneDropDown = uidropdown(app.UIFigure);
            app.SelecioneDropDown.Items = {'Araguatins (ARG)', 'Jataí (JAT)', 'São José dos Campos (SJC)'};
            app.SelecioneDropDown.ValueChangedFcn = createCallbackFcn(app, @SelecioneDropDownValueChanged, true);
            app.SelecioneDropDown.Value = 'Araguatins (ARG)';
            app.PastaSelecionadaEditField = uieditfield(app.UIFigure, 'text');
            app.SelecionePastaButton = uibutton(app.UIFigure, 'push');
            app.SelecionePastaButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionePastaButtonPushed, true);
            app.SelecionePastaButton.Text = 'Selecione a pasta que contém os arquivos (.txt)';
            app.SelecioneoperododeanliseLabel = uilabel(app.UIFigure);
            app.SelecioneoperododeanliseLabel.Text = 'Selecione o período de análise:';
            app.MsDropDownLabel = uilabel(app.UIFigure);
            app.MsDropDownLabel.Text = 'Mês';
            app.MesDropDown = uidropdown(app.UIFigure);
            app.MesDropDown.Items = {'Janeiro', 'Fevereiro', 'Março', 'Abril', 'Maio', 'Junho', 'Julho', 'Agosto', 'Setembro', 'Outubro', 'Novembro', 'Dezembro'};
            app.MesDropDown.ValueChangedFcn = createCallbackFcn(app, @MesDropDownValueChanged, true);
            app.MesDropDown.Value = 'Janeiro';
            app.DataInicialEditFieldLabel = uilabel(app.UIFigure);
            app.DataInicialEditFieldLabel.Text = 'Data Inicial';
            app.DataInicialEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataInicialEditField.ValueChangedFcn = createCallbackFcn(app, @DataInicialEditFieldValueChanged, true);
            app.AnoEditFieldLabel = uilabel(app.UIFigure);
            app.AnoEditFieldLabel.Text = 'Ano';
            app.AnoEditField = uieditfield(app.UIFigure, 'numeric');
            app.AnoEditField.ValueChangedFcn = createCallbackFcn(app, @AnoEditFieldValueChanged, true);
            app.OMNISelecionadoEditField = uieditfield(app.UIFigure, 'text');
            app.OMNIButton = uibutton(app.UIFigure, 'push');
            app.OMNIButton.ButtonPushedFcn = createCallbackFcn(app, @OMNIButtonPushed, true);
            app.OMNIButton.Text = 'Selecione o arquivo do OMNI';
            app.MediaSelecionadaEditField = uieditfield(app.UIFigure, 'text');
            app.MediaButton = uibutton(app.UIFigure, 'push');
            app.MediaButton.ButtonPushedFcn = createCallbackFcn(app, @MediaButtonPushed, true);
            app.MediaButton.Text = 'Selecione o arquivo da média e dos desvios (.mat)';
            app.DataFinalEditFieldLabel = uilabel(app.UIFigure);
            app.DataFinalEditFieldLabel.Text = 'Data Final';
            app.DataFinalEditField = uieditfield(app.UIFigure, 'numeric');
            app.DataFinalEditField.ValueChangedFcn = createCallbackFcn(app, @DataFinalEditFieldValueChanged, true);
            app.PlotarGraficoButton = uibutton(app.UIFigure, 'push');
            app.PlotarGraficoButton.ButtonPushedFcn = createCallbackFcn(app, @PlotarGraficoButtonPushed, true);
            app.PlotarGraficoButton.Text = 'Plotar Gráfico';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = plotagem_graficos

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end