classdef programas < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ConversodosDadosTab            matlab.ui.container.Tab
        SelecioneaestaoDropDownLabel   matlab.ui.control.Label
        SelecioneDropDown              matlab.ui.control.DropDown
        SelecionarPastaButton          matlab.ui.control.Button
        PastaSelecionadaLabel          matlab.ui.control.Label
        NenhumapastaselecionadaLabel   matlab.ui.control.Label
        ConverterDadosButton           matlab.ui.control.Button
        StatusLabel                    matlab.ui.control.Label
        StatusTextArea                 matlab.ui.control.TextArea
        VoltarMenuButton               matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        SelecioneaPastaparaExportarButton  matlab.ui.control.Button
        PastaSelecionada2Label         matlab.ui.control.Label
        Nenhumapastaselecionada2Label  matlab.ui.control.Label
        ImportaocaodosParmetrosTab     matlab.ui.container.Tab
        TratamentodeDadosdoOMNILabel   matlab.ui.control.Label
        InstrucoesLabel                matlab.ui.control.Label
        Passo1Label                    matlab.ui.control.Label
        Passo2Label                    matlab.ui.control.Label
        Passo3Label                    matlab.ui.control.Label
        Passo4Label                    matlab.ui.control.Label
        AcessarsiteOMNIButton          matlab.ui.control.Button
        ArqParametrosLabel             matlab.ui.control.Label
        ArqParametrosEditField         matlab.ui.control.EditField
        SelecionarArqParamButton       matlab.ui.control.Button
        ArqDadosLabel                  matlab.ui.control.Label
        ArqDadosEditField              matlab.ui.control.EditField
        SelecionarArqDadosButton       matlab.ui.control.Button
        SalvarDadosTratadosButton      matlab.ui.control.Button
        PlotagemdosGraficosTab         matlab.ui.container.Tab
        ComparacaodosGraficosTab       matlab.ui.container.Tab
    end

    
    properties (Access = private)
        selectedFolder = ""; % Caminho da pasta selecionada
        exportFolder = "";        % Pasta onde salvar os dados convertidos
    end
     

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.UIFigure.Name = 'IOLANDA';
        end

        % Callback function
        function ConversodosDadosButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            conversao_dados();     % Abre a tela de conversão dos dados
        end

        % Button pushed function: VoltarMenuButton
        function VoltarMenuButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            main_menu();          % Abre novamente a tela de menu
        end

        % Button pushed function: SelecionarPastaButton
        function SelecionarPastaButtonPushed(app, event)
            pasta = uigetdir('', 'Selecione a pasta onde os dados foram reduzidos');

            if pasta ~= 0
                app.selectedFolder = pasta;
                app.NenhumapastaselecionadaLabel.Text = pasta;
                app.StatusTextArea.Value = {'Pasta selecionada com sucesso.'};
            else
                app.NenhumapastaselecionadaLabel.Text = 'Nenhuma pasta selecionada';
                app.selectedFolder = "";
                app.StatusTextArea.Value = {'Seleção de pasta cancelada.'};
            end
        end

        % Button pushed function: ConverterDadosButton
        function ConverterDadosButtonPushed(app, event)
          % Button pushed function: ConverterDadosButton
                % Obter a pasta base a partir da Label da interface
                pastaBase = app.NenhumapastaselecionadaLabel.Text;
                
                if strcmp(pastaBase, 'Nenhuma pasta selecionada') || ~isfolder(pastaBase)
                    uialert(app.UIFigure, 'Por favor, selecione a pasta contendo os dados antes de converter.', 'Erro');
                    return;
                end
            
                % Definir extensão com base na estação
                estacao = app.SelecioneDropDown.Value;
                switch estacao
                    case 'São José dos Campos (SJC)'
                        extensao = 'SJC';
                        nomeConcatenado = 'dados_concatenados_SJC.txt';
                    case 'Araguatins (ARG)'
                        extensao = 'ARG';
                        nomeConcatenado = 'dados_concatenados_ARG.txt';
                    case 'Jataí (JAT)'
                        extensao = 'JAT';
                        nomeConcatenado = 'dados_concatenados_JAT.txt';
                    otherwise
                        uialert(app.UIFigure, 'Estação inválida.', 'Erro');
                        return;
                end
                
                % PERGUNTAR AO USUÁRIO ONDE SALVAR OS ARQUIVOS FORMATADOS
                pastaDestino = uigetdir('', 'Selecione a pasta para salvar os dados convertidos');
                if pastaDestino == 0
                    app.StatusTextArea.Value = {'Operação cancelada pelo usuário.'};
                    return;
                end
                
                % Criar subpastas organizadas dentro da pasta selecionada pelo usuário
                pastaFormatados = fullfile(pastaDestino, 'Arquivos_Formatados_Individuais');
                pastaConcatenados = fullfile(pastaDestino, 'Arquivos_Concatenados');
                
                % Criar as pastas se não existirem
                if ~exist(pastaFormatados, 'dir')
                    mkdir(pastaFormatados);
                end
                if ~exist(pastaConcatenados, 'dir')
                    mkdir(pastaConcatenados);
                end
            
                app.StatusTextArea.Value = "Iniciando conversão e formatação...";
                drawnow; % Atualiza a interface imediatamente
            
                dias = dir(pastaBase);
                dias = dias([dias.isdir]);
                dias = dias(~ismember({dias.name}, {'.', '..', 'Convertidos'}));
            
                totalConvertidos = 0;
                totalErros = 0;
                logs = {};
                conteudoTotal = "";
            
                % Mostrar barra de progresso
                d = uiprogressdlg(app.UIFigure, 'Title', 'Processando arquivos',...
                    'Message', 'Convertendo dados, aguarde...', 'Indeterminate', 'on');
            
                for i = 1:length(dias)
                    pastaDia = fullfile(pastaBase, dias(i).name, 'Dados Reduzidos');
                    if isfolder(pastaDia)
                        arquivos = dir(fullfile(pastaDia, ['*.' extensao]));
                        arquivos = arquivos(~contains({arquivos.name}, 'Filtro', 'IgnoreCase', true));
            
                        for j = 1:length(arquivos)
                            try
                                arquivoOrigem = fullfile(pastaDia, arquivos(j).name);
                                
                                % Processar e formatar o arquivo
                                [dadosFormatados, sucesso] = app.processarArquivoCADI(arquivoOrigem, arquivos(j).name);
                                
                                if sucesso
                                    % Salvar arquivo formatado individualmente
                                    nomeArquivoFormatado = ['formatado_' strrep(arquivos(j).name, ['.' extensao], '.txt')];
                                    caminhoFormatado = fullfile(pastaFormatados, nomeArquivoFormatado);
                                    
                                    fid = fopen(caminhoFormatado, 'w');
                                    fprintf(fid, '%s\n', 'yyyy.MM.dd (DDD) HH:mm:ss   foF2    h''F    hpF2');
                                    fprintf(fid, '%s', dadosFormatados);
                                    fclose(fid);
                                    
                                    % Adicionar ao conteúdo concatenado
                                    conteudoTotal = conteudoTotal + dadosFormatados;
                                    
                                    totalConvertidos = totalConvertidos + 1;
                                    logs{end+1} = "✓ Formatado: " + arquivos(j).name;
                                else
                                    totalErros = totalErros + 1;
                                    logs{end+1} = "✗ Erro ao formatar: " + arquivos(j).name;
                                end
            
                            catch ME
                                totalErros = totalErros + 1;
                                logs{end+1} = "✗ Erro ao processar " + arquivos(j).name + ": " + ME.message;
                            end
                        end
                    end
                end
            
                % Fechar a barra de progresso
                close(d);
                
                % Salvar arquivo final concatenado
                arquivoFinal = fullfile(pastaConcatenados, nomeConcatenado);
                try
                    fid = fopen(arquivoFinal, 'w');
                    fprintf(fid, '%s\n', 'yyyy.MM.dd (DDD) HH:mm:ss   foF2    h''F    hpF2');
                    fprintf(fid, '%s', conteudoTotal);
                    fclose(fid);
                    logs{end+1} = "✅ Arquivo concatenado salvo em: " + arquivoFinal;
                    
                    % Mostrar mensagem de conclusão
                    msg = sprintf('Conversão concluída com sucesso!\n\nArquivos convertidos: %d\nErros: %d\n\nArquivos individuais salvos em: %s\nArquivo concatenado salvo em: %s', ...
                        totalConvertidos, totalErros, pastaFormatados, arquivoFinal);
                    
                    uialert(app.UIFigure, msg, 'Conversão Concluída', 'Icon', 'success');
                    
                catch ME
                    logs{end+1} = "❌ Erro ao salvar arquivo concatenado: " + ME.message;
                    uialert(app.UIFigure, 'Erro ao salvar arquivo concatenado.', 'Erro', 'Icon', 'error');
                end
            
                % CORREÇÃO: Garantir que logs não esteja vazio antes de atualizar o StatusTextArea
                if isempty(logs)
                    logs = {'Nenhum arquivo processado.'};
                end
                
                % CORREÇÃO: Criar array de células corretamente
                statusFinal = cell(1, length(logs) + 1);
                statusFinal{1} = sprintf('✅ CONVERSÃO CONCLUÍDA\n📊 Convertidos: %d\n❌ Erros: %d\n\n', totalConvertidos, totalErros);
                
                for k = 1:length(logs)
                    statusFinal{k+1} = logs{k};
                end
                
                % Atualizar status na interface
                app.StatusTextArea.Value = statusFinal;
        end
    end
        end

        % Button pushed function: SelecioneaPastaparaExportarButton
        function SelecioneaPastaparaExportarButtonPushed(app, event)
            pastaExport = uigetdir('', 'Selecione a pasta para salvar os dados convertidos');
            if pastaExport ~= 0
                app.exportFolder = pastaExport;
                app.PastaSelecionada2Label.Text = ['Pasta de exportação definida: ', pastaExport];
            else
                app.Nenhumapastaselecionada2Label.Text = 'Seleção de pasta para exportação cancelada.';
            end
        end

        % Button pushed function: AcessarsiteOMNIButton
        function AcessarsiteOMNIButtonPushed(app, event)
           web('https://omniweb.gsfc.nasa.gov/form/omni_min.html','-browser');
        end

        % Button pushed function: SelecionarArqParamButton
        function SelecionarArqParamButtonPushed(app, event)
            % Mensagem para o usuário
%             drawnow;
%             uiwait(msgbox('Selecione o arquivo de PARÂMETROS e confirme.', ...
%                 'Selecionar arquivo', 'modal'));
        
            % Janela para escolher arquivo
            [f,p] = uigetfile({'*.txt','Arquivos TXT (*.txt)'}, ...
                              'Selecione o arquivo de PARÂMETROS');
        
            % Se o usuário não cancelou
            if f ~= 0
                headerFile = fullfile(p,f);
        
            % Envia o caminho para o campo EditField da interface
            app.ArqParametrosEditField.Value = headerFile;
       
            end
        end

        % Button pushed function: SelecionarArqDadosButton
        function SelecionarArqDadosButtonPushed(app, event)
            % Mensagem ao usuário
            drawnow;
%             uiwait(msgbox('Selecione o arquivo de DADOS e confirme.', ...
%                           'Selecionar arquivo', 'modal'));
        
            % Seleção do arquivo
            [f,p] = uigetfile({'*.txt','Arquivos TXT (*.txt)'}, ...
                              'Selecione o arquivo de DADOS');
        
            % Se o usuário não cancelou
            if f ~= 0
                dataFile = fullfile(p,f);
        
                % Atualiza o campo de texto da interface
                app.ArqDadosEditField.Value = dataFile;

            end
        end

        % Button pushed function: SalvarDadosTratadosButton
        function SalvarDadosTratadosButtonPushed(app, event)
        % ============================================================
        % VERIFICAÇÃO INICIAL
        % ============================================================
        headerFile = app.ArqParametrosEditField.Value;
        dataFile   = app.ArqDadosEditField.Value;
        
        if isempty(headerFile) || isempty(dataFile)
            uialert(app.UIFigure,'Selecione os dois arquivos TXT antes de continuar.','Erro');
            return;
        end
        
        try
            % ============================================================
            % 1) LER ARQUIVO DE PARÂMETROS / CABEÇALHO
            % ============================================================
            rawHeader = fileread(headerFile);
            lines = splitlines(rawHeader);
            
            colNames = {};
            colIndex = [];
            
            for i = 1:numel(lines)
                line = strtrim(lines{i});
                if isempty(line), continue; end
                
                C = strsplit(line);
                
                % linha válida?
                if ~isempty(regexp(C{1}, '^\d+$','once'))
                    idx = str2double(C{1});
                    fullName = strtrim(strjoin(C(2:end),' '));
                    fullLower = lower(fullName);
                    
                    % ----------------------------------------------------
                    % Pequeno mapeamento direto (sem função)
                    % ----------------------------------------------------
                    if contains(fullLower,'year')
                        ab = 'Year';
                    elseif contains(fullLower,'day')
                        ab = 'Day';
                    elseif contains(fullLower,'hour')
                        ab = 'Hour';
                    elseif contains(fullLower,'minute')
                        ab = 'Min';
                    elseif contains(fullLower,'field magnitude')
                        ab = 'Fma';
                    elseif contains(fullLower,'bx')
                        ab = 'Bx';
                    elseif contains(fullLower,'by') && contains(fullLower,'gsm')
                        ab = 'ByG';
                    elseif contains(fullLower,'bz') && contains(fullLower,'gsm')
                        ab = 'BzG';
                    elseif contains(fullLower,'by') && contains(fullLower,'gse')
                        ab = 'ByE';
                    elseif contains(fullLower,'bz') && contains(fullLower,'gse')
                        ab = 'BzE';
                    elseif contains(fullLower,'flow speed')
                        ab = 'Spe';
                    elseif contains(fullLower,'velocity') && contains(fullLower,'vx')
                        ab = 'Vx';
                    elseif contains(fullLower,'velocity') && contains(fullLower,'vy')
                        ab = 'Vy';
                    elseif contains(fullLower,'velocity') && contains(fullLower,'vz')
                        ab = 'Vz';
                    elseif contains(fullLower,'proton density')
                        ab = 'Den';
                    elseif contains(fullLower,'proton temperature')
                        ab = 'Pro';
                    elseif contains(fullLower,'flow pressure')
                        ab = 'Pre';
                    elseif contains(fullLower,'electric field')
                        ab = 'Ey';
                    elseif contains(fullLower,'plasma beta')
                        ab = 'Bet';
                    elseif contains(fullLower,'alfven mach')
                        ab = 'Amn';
                    elseif contains(fullLower,'magnetosonic mach')
                        ab = 'Mmn';
                    elseif contains(fullLower,'ae index')
                        ab = 'AE';
                    elseif contains(fullLower,'al index')
                        ab = 'AL';
                    elseif contains(fullLower,'au index')
                        ab = 'AU';
                    elseif contains(fullLower,'sym/d')
                        ab = 'SYMd';
                    elseif contains(fullLower,'sym/h')
                        ab = 'SYMh';
                    elseif contains(fullLower,'asy/d')
                        ab = 'ASYd';
                    elseif contains(fullLower,'asy/h')
                        ab = 'ASYh';
                    elseif contains(fullLower,'polar cap') || contains(fullLower,'pc index')
                        ab = 'PC';
                        
                    else
                        % fallback simples
                        tokens = regexp(fullName,'[A-Za-z]+','match');
                        if isempty(tokens)
                            ab = sprintf('C%02d', idx);
                        else
                            base = tokens{1};
                            ab = upper(base(1:min(3,end)));
                        end
                    end
                    
                    colIndex(end+1) = idx;
                    colNames{end+1} = ab;
                end
            end
            
            % ============================================================
            % 2) LER ARQUIVO DE DADOS DO OMNI — CORRIGIDO
            % ============================================================
            fid = fopen(dataFile);
            
            fmt = repmat('%f',1,max(colIndex));
            raw = textscan(fid, fmt, 'Delimiter', {' ','\t'}, 'MultipleDelimsAsOne', true);
            fclose(fid);
            
            % Verificar tamanhos (debug opcional)
            %                 for k = 1:numel(raw)
            %                     fprintf('Coluna %d tem %d linhas\n', k, numel(raw{k}));
            %                 end
            
            % Garantir tamanho igual em todas as colunas
            nRows = max(cellfun(@numel, raw));
            nCols = numel(colIndex);
            
            M = NaN(nRows, nCols);  % inicia matriz completa
            
            for c = 1:nCols
                if ~isempty(raw{c})
                    M(1:numel(raw{c}), c) = raw{c};
                end
            end
            
            % ============================================================
            % 3) CORRIGIR VALORES INVÁLIDOS (99999 etc.)
            % ============================================================
            for ii = 1:numel(M)
                val = M(ii);
                if isnan(val), continue; end
                
                txt = regexprep(sprintf('%.3f', val), '[0.]','');
                if ~isempty(txt) && all(txt=='9')
                    M(ii) = NaN;
                end
            end
            
            % ============================================================
            % 4) CONVERTER MATRIZ PARA STRINGS
            % ============================================================
            nRows = size(M,1);
            nCols = size(M,2);
            strData = cell(nRows, nCols);
            
            for i = 1:nRows
                for j = 1:nCols
                    val = M(i,j);
                    
                    if isnan(val)
                        strData{i,j} = 'NaN';
                        continue;
                    end
                    
                    if j <= 4
                        strData{i,j} = sprintf('%d', round(val));
                        continue;
                    end
                    
                    nomeCol = colNames{j};
                    isIndice = any(strcmpi(nomeCol, {'AE','AL','AU','SYMD','SYMH','ASYD','ASYH','PC','DST'}));
                    
                    if isIndice
                        strData{i,j} = sprintf('%d', round(val));
                    else
                        if mod(val,1) ~= 0
                            strData{i,j} = num2str(val);
                        else
                            if abs(val) >= 1000
                                strData{i,j} = sprintf('%.1f', val);
                            else
                                strData{i,j} = sprintf('%d', round(val));
                            end
                        end
                    end
                end
            end
            
            % ============================================================
            % 5) CALCULAR LARGURA DE CADA COLUNA
            % ============================================================
            colWidths = zeros(1,nCols);
            
            for j = 1:nCols
                lengths = cellfun(@length, strData(:,j));
                colWidths(j) = max(max(lengths), length(colNames{j})) + 2;
            end
            
            % ============================================================
            % 6) GERAR ARQUIVO TRATADO
            % ============================================================
            [p,n,~] = fileparts(dataFile);          % p = pasta, n = nome sem extensão
            
            % Evita duplicar "omni" no nome
            if startsWith(lower(n),'omni')
                baseName = n;
            else
                baseName = ['omni' n];
            end
            
            newFileName = [baseName '_tratados.txt'];  % monta nome novo
            newFile = fullfile(p, newFileName);
            
            fid = fopen(newFile,'w');
            
            % Cabeçalho
            for j = 1:nCols
                fprintf(fid, sprintf('%%-%ds', colWidths(j)), colNames{j});
            end
            fprintf(fid,'\n');
            
            % Dados
            for i = 1:nRows
                for j = 1:nCols
                    fprintf(fid, sprintf('%%-%ds', colWidths(j)), strData{i,j});
                end
                fprintf(fid,'\n');
            end
            
            fclose(fid);
            
            % ============================================================
            % FINAL — mensagem bonitinha
            % ============================================================
            msg = sprintf(['Arquivo tratado criado com sucesso!\n\n', ...
                'Nome do arquivo: %s\n', ...
                'Localização: %s'], newFileName, p);
            
            uialert(app.UIFigure, msg, 'Sucesso', 'Icon', 'info');
            
            % Abre o arquivo no editor
            edit(newFile);
            
        catch ME
            uialert(app.UIFigure, ['Erro ao processar os arquivos: ' ME.message], 'Erro');
        end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConversodosDadosTab = uitab(app.TabGroup);
            app.ConversodosDadosTab.Title = 'Conversão dos Dados';
            app.SelecioneaestaoDropDownLabel = uilabel(app.ConversodosDadosTab);
            app.SelecioneaestaoDropDownLabel.Text = 'Selecione a estação: ';
            app.SelecioneDropDown = uidropdown(app.ConversodosDadosTab);
            app.SelecioneDropDown.Items = {'Araguatins (ARG)', 'Jataí (JAT)', 'São José dos Campos (SJC)'};
            app.SelecioneDropDown.Value = 'Araguatins (ARG)';
            app.SelecionarPastaButton = uibutton(app.ConversodosDadosTab, 'push');
            app.SelecionarPastaButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarPastaButtonPushed, true);
            app.SelecionarPastaButton.Text = 'Selecione a Pasta da Redução';
            app.PastaSelecionadaLabel = uilabel(app.ConversodosDadosTab);
            app.PastaSelecionadaLabel.Text = 'Pasta Selecionada:';
            app.NenhumapastaselecionadaLabel = uilabel(app.ConversodosDadosTab);
            app.NenhumapastaselecionadaLabel.Text = 'Nenhuma pasta selecionada';
            app.ConverterDadosButton = uibutton(app.ConversodosDadosTab, 'push');
            app.ConverterDadosButton.ButtonPushedFcn = createCallbackFcn(app, @ConverterDadosButtonPushed, true);
            app.ConverterDadosButton.Text = 'Converter Dados';
            app.StatusLabel = uilabel(app.ConversodosDadosTab);
            app.StatusLabel.Text = 'Status:';
            app.StatusTextArea = uitextarea(app.ConversodosDadosTab);
            app.VoltarMenuButton = uibutton(app.ConversodosDadosTab, 'push');
            app.VoltarMenuButton.ButtonPushedFcn = createCallbackFcn(app, @VoltarMenuButtonPushed, true);
            app.VoltarMenuButton.Text = 'Voltar ao Menu';
            app.Image = uiimage(app.ConversodosDadosTab);
            app.Image.ImageSource = 'logo-horizontal.png';
            app.SelecioneaPastaparaExportarButton = uibutton(app.ConversodosDadosTab, 'push');
            app.SelecioneaPastaparaExportarButton.ButtonPushedFcn = createCallbackFcn(app, @SelecioneaPastaparaExportarButtonPushed, true);
            app.SelecioneaPastaparaExportarButton.Text = 'Selecione a Pasta para Exportar';
            app.PastaSelecionada2Label = uilabel(app.ConversodosDadosTab);
            app.PastaSelecionada2Label.Text = 'Pasta Selecionada:';
            app.Nenhumapastaselecionada2Label = uilabel(app.ConversodosDadosTab);
            app.Nenhumapastaselecionada2Label.Text = 'Nenhuma pasta selecionada';
            app.ImportaocaodosParmetrosTab = uitab(app.TabGroup);
            app.ImportaocaodosParmetrosTab.Title = 'Importação dos Parâmetros';
            app.TratamentodeDadosdoOMNILabel = uilabel(app.ImportaocaodosParmetrosTab);
            app.TratamentodeDadosdoOMNILabel.Text = 'Tratamento de Dados do OMNI';
            app.InstrucoesLabel = uilabel(app.ImportaocaodosParmetrosTab);
            app.InstrucoesLabel.Text = 'Instruções';
            app.Passo1Label = uilabel(app.ImportaocaodosParmetrosTab);
            app.Passo1Label.Text = 'Passo 1: Acesse o site do OMNI e gere os arquivos';
            app.Passo2Label = uilabel(app.ImportaocaodosParmetrosTab);
            app.Passo2Label.Text = 'Passo 2: Baixe o TXT de PARÂMETROS (Create ASCII File)';
            app.Passo3Label = uilabel(app.ImportaocaodosParmetrosTab);
            app.Passo3Label.Text = 'Passo 3:  Baixe o TXT dos DADOS';
            app.Passo4Label = uilabel(app.ImportaocaodosParmetrosTab);
            app.Passo4Label.Text = 'Passo 4: Selecione os dois arquivos abaixo';
            app.AcessarsiteOMNIButton = uibutton(app.ImportaocaodosParmetrosTab, 'push');
            app.AcessarsiteOMNIButton.ButtonPushedFcn = createCallbackFcn(app, @AcessarsiteOMNIButtonPushed, true);
            app.AcessarsiteOMNIButton.Text = 'Acessar site OMNI';
            app.ArqParametrosLabel = uilabel(app.ImportaocaodosParmetrosTab);
            app.ArqParametrosLabel.Text = 'Arquivo dos PARÂMETROS:';
            app.ArqParametrosEditField = uieditfield(app.ImportaocaodosParmetrosTab, 'text');
            app.SelecionarArqParamButton = uibutton(app.ImportaocaodosParmetrosTab, 'push');
            app.SelecionarArqParamButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarArqParamButtonPushed, true);
            app.SelecionarArqParamButton.Text = 'Selecionar Arquivo';
            app.ArqDadosLabel = uilabel(app.ImportaocaodosParmetrosTab);
            app.ArqDadosLabel.Text = 'Arquivo dos DADOS:';
            app.ArqDadosEditField = uieditfield(app.ImportaocaodosParmetrosTab, 'text');
            app.SelecionarArqDadosButton = uibutton(app.ImportaocaodosParmetrosTab, 'push');
            app.SelecionarArqDadosButton.ButtonPushedFcn = createCallbackFcn(app, @SelecionarArqDadosButtonPushed, true);
            app.SelecionarArqDadosButton.Text = 'Selecionar Arquivo';
            app.SalvarDadosTratadosButton = uibutton(app.ImportaocaodosParmetrosTab, 'push');
            app.SalvarDadosTratadosButton.ButtonPushedFcn = createCallbackFcn(app, @SalvarDadosTratadosButtonPushed, true);
            app.SalvarDadosTratadosButton.Text = 'Salvar Dados Tratados';
            app.PlotagemdosGraficosTab = uitab(app.TabGroup);
            app.PlotagemdosGraficosTab.Title = 'Plotagem dos Gráficos';
            app.ComparacaodosGraficosTab = uitab(app.TabGroup);
            app.ComparacaodosGraficosTab.Title = 'Comparação dos Gráficos';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = programas

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end