classdef programas_oficial < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        CinzaFundo                      matlab.ui.control.EditField
        ConversodosDadosButton          matlab.ui.control.Button
        ObservatoriosCadastradosButton  matlab.ui.control.Button
        PlotagemdosGraficosButton       matlab.ui.control.Button
        ContatoButton                   matlab.ui.control.Button
        CinzaClaroMenu                  matlab.ui.control.EditField
        Image                           matlab.ui.control.Image
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ConversodosDadosButton
        function ConversodosDadosButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            conversao_dados();     % Abre a tela de conversão dos dados
        end

        % Button pushed function: ObservatoriosCadastradosButton
        function ObservatoriosCadastradosButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            dados_omni();          % Abre a tela dos dados do ommi
        end

        % Button pushed function: PlotagemdosGraficosButton
        function PlotagemdosGraficosButtonPushed(app, event)
            delete(app.UIFigure);  % Fecha a tela atual 
            processarPlot();   % Abre a tela da plotagem dos gráficos
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.CinzaFundo = uieditfield(app.UIFigure, 'text');
            app.CinzaFundo.Editable = 'off';
            app.ConversodosDadosButton = uibutton(app.UIFigure, 'push');
            app.ConversodosDadosButton.ButtonPushedFcn = createCallbackFcn(app, @ConversodosDadosButtonPushed, true);
            app.ConversodosDadosButton.Text = 'Conversão dos Dados';
            app.ObservatoriosCadastradosButton = uibutton(app.UIFigure, 'push');
            app.ObservatoriosCadastradosButton.ButtonPushedFcn = createCallbackFcn(app, @ObservatoriosCadastradosButtonPushed, true);
            app.ObservatoriosCadastradosButton.Text = 'Parâmetros OMNI';
            app.PlotagemdosGraficosButton = uibutton(app.UIFigure, 'push');
            app.PlotagemdosGraficosButton.ButtonPushedFcn = createCallbackFcn(app, @PlotagemdosGraficosButtonPushed, true);
            app.PlotagemdosGraficosButton.Text = 'Plotagem dos Gráficos';
            app.ContatoButton = uibutton(app.UIFigure, 'push');
            app.ContatoButton.Text = 'Comparação dos Gráficos';
            app.CinzaClaroMenu = uieditfield(app.UIFigure, 'text');
            app.CinzaClaroMenu.Editable = 'off';
            app.CinzaClaroMenu.Value = 'Menu Secundário';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = 'logo-horizontal.png';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = programas_oficial

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end